classdef iSIM_decon_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Copyright                      matlab.ui.control.Label
        ProceedButton                  matlab.ui.control.Button
        IterNumLabel                   matlab.ui.control.Label
        IterNumEditField_DAPI          matlab.ui.control.NumericEditField
        IterNumEditField_GFP           matlab.ui.control.NumericEditField
        IterNumEditField_mCherry       matlab.ui.control.NumericEditField
        IterNumEditField_cy5           matlab.ui.control.NumericEditField
        UPLSAPO100XOPanel              matlab.ui.container.Panel
        TabGroup_100X                  matlab.ui.container.TabGroup
        iSIMTab_100X                   matlab.ui.container.Tab
        BackgroundLabel_100X_iSIM      matlab.ui.control.Label
        BackgroundEditField_100X_iSIM_DAPI  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_iSIM_GFP  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_iSIM_mCherry  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_iSIM_cy5  matlab.ui.control.NumericEditField
        ZnmLabel_100X_iSIM             matlab.ui.control.Label
        XYnmLabel_100X_iSIM            matlab.ui.control.Label
        ZEditField_100X_iSIM_cy5       matlab.ui.control.NumericEditField
        XYEditField_100X_iSIM_cy5      matlab.ui.control.NumericEditField
        cy5Label_100X_iSIM             matlab.ui.control.Label
        ZEditField_100X_iSIM_mCherry   matlab.ui.control.NumericEditField
        XYEditField_100X_iSIM_mCherry  matlab.ui.control.NumericEditField
        ZEditField_100X_iSIM_GFP       matlab.ui.control.NumericEditField
        XYEditField_100X_iSIM_GFP      matlab.ui.control.NumericEditField
        ZEditField_100X_iSIM_DAPI      matlab.ui.control.NumericEditField
        XYEditField_100X_iSIM_DAPI     matlab.ui.control.NumericEditField
        mCherryLabel_100X_iSIM         matlab.ui.control.Label
        GFPLabel_100X_iSIM             matlab.ui.control.Label
        DAPILabel_100X_iSIM            matlab.ui.control.Label
        EpiTab_100X                    matlab.ui.container.Tab
        BackgroundLabel_100X_Epi       matlab.ui.control.Label
        BackgroundEditField_100X_Epi_DAPI  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_Epi_GFP  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_Epi_mCherry  matlab.ui.control.NumericEditField
        BackgroundEditField_100X_Epi_cy5  matlab.ui.control.NumericEditField
        ZnmLabel_100X_Epi              matlab.ui.control.Label
        XYnmLabel_100X_Epi             matlab.ui.control.Label
        ZEditField_100X_Epi_cy5        matlab.ui.control.NumericEditField
        XYEditField_100X_Epi_cy5       matlab.ui.control.NumericEditField
        cy5Label_100X_Epi              matlab.ui.control.Label
        ZEditField_100X_Epi_mCherry    matlab.ui.control.NumericEditField
        XYEditField_100X_Epi_mCherry   matlab.ui.control.NumericEditField
        ZEditField_100X_Epi_GFP        matlab.ui.control.NumericEditField
        XYEditField_100X_Epi_GFP       matlab.ui.control.NumericEditField
        ZEditField_100X_Epi_DAPI       matlab.ui.control.NumericEditField
        XYEditField_100X_Epi_DAPI      matlab.ui.control.NumericEditField
        mCherryLabel_100X_Epi          matlab.ui.control.Label
        GFPLabel_100X_Epi              matlab.ui.control.Label
        DAPILabel_100X_Epi             matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        filepathEditField              matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        UPLSAPO60XSPanel               matlab.ui.container.Panel
        TabGroup_60X                   matlab.ui.container.TabGroup
        iSIMTab_60X                    matlab.ui.container.Tab
        BackgroundLabel_60X_iSIM       matlab.ui.control.Label
        BackgroundEditField_60X_iSIM_DAPI  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_iSIM_GFP  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_iSIM_mCherry  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_iSIM_cy5  matlab.ui.control.NumericEditField
        ZnmLabel_60X_iSIM              matlab.ui.control.Label
        XYnmLabel_60X_iSIM             matlab.ui.control.Label
        ZEditField_60X_iSIM_cy5        matlab.ui.control.NumericEditField
        XYEditField_60X_iSIM_cy5       matlab.ui.control.NumericEditField
        cy5Label_60X_iSIM              matlab.ui.control.Label
        ZEditField_60X_iSIM_mCherry    matlab.ui.control.NumericEditField
        XYEditField_60X_iSIM_mCherry   matlab.ui.control.NumericEditField
        ZEditField_60X_iSIM_GFP        matlab.ui.control.NumericEditField
        XYEditField_60X_iSIM_GFP       matlab.ui.control.NumericEditField
        ZEditField_60X_iSIM_DAPI       matlab.ui.control.NumericEditField
        XYEditField_60X_iSIM_DAPI      matlab.ui.control.NumericEditField
        mCherryLabel_60X_iSIM          matlab.ui.control.Label
        GFPLabel_60X_iSIM              matlab.ui.control.Label
        DAPILabel_60X_iSIM             matlab.ui.control.Label
        EpiTab_60X                     matlab.ui.container.Tab
        BackgroundLabel_60X_Epi        matlab.ui.control.Label
        BackgroundEditField_60X_Epi_DAPI  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_Epi_GFP  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_Epi_mCherry  matlab.ui.control.NumericEditField
        BackgroundEditField_60X_Epi_cy5  matlab.ui.control.NumericEditField
        ZnmLabel_60X_Epi               matlab.ui.control.Label
        XYnmLabel_60X_Epi              matlab.ui.control.Label
        ZEditField_60X_Epi_cy5         matlab.ui.control.NumericEditField
        XYEditField_60X_Epi_cy5        matlab.ui.control.NumericEditField
        cy5Label_60X_Epi               matlab.ui.control.Label
        ZEditField_60X_Epi_mCherry     matlab.ui.control.NumericEditField
        XYEditField_60X_Epi_mCherry    matlab.ui.control.NumericEditField
        ZEditField_60X_Epi_GFP         matlab.ui.control.NumericEditField
        XYEditField_60X_Epi_GFP        matlab.ui.control.NumericEditField
        ZEditField_60X_Epi_DAPI        matlab.ui.control.NumericEditField
        XYEditField_60X_Epi_DAPI       matlab.ui.control.NumericEditField
        mCherryLabel_60X_Epi           matlab.ui.control.Label
        GFPLabel_60X_Epi               matlab.ui.control.Label
        DAPILabel_60X_Epi              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file, path] = uigetfile('*.tif', 'Choose any one of raw data');
            app.filepathEditField.Value = fullfile(path, file);
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.ProceedButton.Enable = "off";
            app.UIFigure.Pointer = 'watch';
            
            % Read tiff header
            [path_data, name_data, ext] = fileparts(app.filepathEditField.Value);
            header = ImageJ_formatted_TIFF.get_header(fullfile(path_data, strcat(name_data, ext)));
            if ~isfield(header.MicroManagerMetadata, 'Objective_Label')
                warndlg('Cannot find objective information. Please make sure you are loading a raw image acquired by Visitech iSIM.','Warning');
                app.UIFigure.Pointer = 'arrow';
                app.ProceedButton.Enable = "on";
                return;
            end
            
            % Objective label
            Objective_Label = [];
            switch header.MicroManagerMetadata.Objective_Label
                case '3-UPLXAPO20X'
                    warndlg('It is an image acquired using UPLXAPO20X. Please make sure you are loading a raw image acquired using 60X or 100X objectives.','Warning');
                    app.UIFigure.Pointer = 'arrow';
                    app.ProceedButton.Enable = "on";
                    return;
                case '5-UPLSAPO60XS'
                    Objective_Label = '60X';
                case '6-UPLSAPO100XO'
                    Objective_Label = '100X';
                otherwise
                    warndlg('Unknown objective label. Please make sure you are loading a raw image acquired by Visitech iSIM.','Warning');
                    app.UIFigure.Pointer = 'arrow';
                    app.ProceedButton.Enable = "on";
                    return;
            end            
            
            % Imaging mode (iSIM or Epi), channel name (DAPI, GFP, mCherry or cy5)
            IJMetadata_channel_num = length(header.IJMetadata.ChNames);
            FWHM_xy = zeros([IJMetadata_channel_num, 1]);
            FWHM_z = zeros([IJMetadata_channel_num, 1]);
            background = zeros([IJMetadata_channel_num, 1]);
            iter_num = zeros([IJMetadata_channel_num, 1]);

            Imaging_Mode = [];
            Channel_Name = [];
            for k = 1:IJMetadata_channel_num
                if contains(header.IJMetadata.ChNames{k}, 'Epi')
                    Imaging_Mode = 'Epi';
                else
                    Imaging_Mode = 'iSIM';
                end
                index = strfind(header.IJMetadata.ChNames{k}, ' - ');
                if ~isempty(index)
                    Channel_Name = header.IJMetadata.ChNames{k}(index + 3: end);
                else
                    Channel_Name = header.IJMetadata.ChNames{k};
                end

                eval(strcat('FWHM_xy(k) = app.XYEditField_', Objective_Label, '_', Imaging_Mode, '_', Channel_Name, '.Value;'));
                eval(strcat('FWHM_z(k) = app.ZEditField_', Objective_Label, '_', Imaging_Mode, '_', Channel_Name, '.Value;'));
                eval(strcat('background(k) = app.BackgroundEditField_', Objective_Label, '_', Imaging_Mode, '_', Channel_Name, '.Value;'));
                eval(strcat('iter_num(k) = app.IterNumEditField_', Channel_Name, '.Value;'));
            end

            % Pixel size
            switch Imaging_Mode
                case 'Epi'
                    FWHM_xy = FWHM_xy / 1000 / double(header.resolution);
                    if isempty(header.spacing)
                        FWHM_z = FWHM_z / 1000 / 0.2;
                    else
                        FWHM_z = FWHM_z / 1000 / abs(header.spacing);
                    end
                case 'iSIM'
                    %                     FWHM_xy = FWHM_xy / 1000 / double(header.resolution) / sqrt(2);
                    FWHM_xy = FWHM_xy / 1000 / double(header.resolution);
                    if isempty(header.spacing)
                        %                         FWHM_z = FWHM_z / 1000 / 0.2 / sqrt(2);
                        FWHM_z = FWHM_z / 1000 / 0.2;
                    else
                        %                         FWHM_z = FWHM_z / 1000 / abs(header.spacing) / sqrt(2);
                        FWHM_z = FWHM_z / 1000 / abs(header.spacing);
                    end
            end
            
            % Axis order
            AxisOrder = [];
            if strcmp(header.IJMetadata.AxisOrder{1}, 'channel') && strcmp(header.IJMetadata.AxisOrder{2}, 'z')
                AxisOrder = 'XYCZPT';
            elseif strcmp(header.IJMetadata.AxisOrder{1}, 'z') && strcmp(header.IJMetadata.AxisOrder{2}, 'channel')
                AxisOrder = 'XYZCPT';
            else
                warndlg('Unknown axis order.','Warning');
                app.UIFigure.Pointer = 'arrow';
                app.ProceedButton.Enable = "on";
                return;
            end
            
            % Create an output folder
            path_output = fullfile(path_data, 'deconvolution_results');
            mkdir(path_output);

            % Read MicroManager tiff files
            k = strfind(name_data, '.ome');
            name_common = name_data(1:k(end) - 1);

            if contains(name_common, '_Default')
                % One position imaging
                k = strfind(name_common, '_Default');
                name_common = name_common(1:k(end) - 1);

                % Check whether multiple MicroManager tiff files as a big tiff (> 4GB)
                tile_num = 0;
                filename = strcat(path_data, '\', name_common, '_Default', ".ome", ext);
                
                while isfile(filename)
                    tile_num = tile_num + 1;
                    filename = strcat(path_data, '\', name_common, '_Default', num2str(tile_num), ".ome", ext);
                end
                for tile_index = 0:(tile_num - 1)
                    if tile_index == 0
                        filename = strcat(path_data, '\', name_common, '_Default', ".ome", ext);
                        [stack, ~] = ImageJ_formatted_TIFF.ReadTifStack(filename, 0);
                    else
                        filename = strcat(path_data, '\', name_common, '_Default', num2str(tile_num), ".ome", ext);
                        [stack_tile, ~] = ImageJ_formatted_TIFF.ReadTifStack(filename, 0);
                        stack = cat(3, stack, stack_tile);
                    end
                end
                
                % Channel number
                if isempty(header.channels)
                    channels = 1;
                else
                    channels = header.channels;
                end
                % 2D slice or 3D stack
                if isempty(header.slices)
                    slices = 1;
                else
                    slices = header.slices;
                end
                % Reshape
                switch AxisOrder
                    case 'XYCZPT'
                        stack = reshape(stack, [header.ImageLength, header.ImageWidth, channels, slices]);
                        stack = permute(stack, [1 2 4 3]);      % Swap channels and slices
                    case 'XYZCPT'
                        stack = reshape(stack, [header.ImageLength, header.ImageWidth, slices, channels]);
                end

                if header.BitsPerSample == 16
                    output_stack = zeros(size(stack), 'uint16');
                else
                    output_stack = zeros(size(stack), 'single');
                end
                
                % RL deconvolution part
                for channel_index = 1:channels          
                    input_image = squeeze(stack(:, :, :, channel_index));
                    input_image = input_image - background(channel_index);              % Subtract background
                    input_image(input_image < 0) = 0;
                    FWHM = [FWHM_xy(channel_index), FWHM_xy(channel_index), FWHM_z(channel_index)];
                    output_image = decon_gpu(input_image, FWHM, iter_num(channel_index));

                    if (header.BitsPerSample == 16)
                        if max(output_image, [], 'all') <= 65535
                            output_image = uint16(output_image);
                        else
                            output_image = uint16(65535 * output_image ./ max(output_image, [], 'all'));
                        end
                    end

                    output_stack(:, :, :, channel_index) = output_image;
                end
                % Save tiff stack file
                filepath_output = strcat(path_output, '\', name_common, '_Default', '_decon.tif');
                if channels == 1
                    ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, header.spacing, 'z');
                else
                    if slices == 1
                        ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, 0.2, 'c');
                    else
                        output_stack = permute(output_stack, [1 2 4 3]);                % Swap channels and slices
                        ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, header.spacing, 'cz');
                    end
                end

            elseif contains(name_common, '_Pos')
                % Multiple position imaging
                k = strfind(name_common, '_Pos');
                name_common = name_common(1:k(end) - 1);
                position_num = 0;
                filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_num), ".ome", ext);
                while isfile(filename)
                    position_num = position_num + 1;
                    filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_num), ".ome", ext);
                end

                for position_index = 0:(position_num - 1)

                    % Check whether multiple MicroManager tiff files as a big tiff (> 4GB)
                    tile_num = 0;
                    filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_index), ".ome", ext);
                    

                    while isfile(filename)
                        tile_num = tile_num + 1;
                        filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_index), '_', num2str(tile_num), ".ome", ext);
                    end

                    for tile_index = 0:(tile_num - 1)
                        if tile_index == 0
                            filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_index), ".ome", ext);
                            [stack, ~] = ImageJ_formatted_TIFF.ReadTifStack(filename, 0);
                        else
                            filename = strcat(path_data, '\', name_common, '_Pos', num2str(position_index), '_', num2str(tile_index), ".ome", ext);
                            [stack_tile, ~] = ImageJ_formatted_TIFF.ReadTifStack(filename, 0);
                            stack = cat(3, stack, stack_tile);
                        end

                    end

                    % Channel number
                    if isempty(header.channels)
                        channels = 1;
                    else
                        channels = header.channels;
                    end
                    % 2D slice or 3D stack
                    if isempty(header.slices)
                        slices = 1;
                    else
                        slices = header.slices;
                    end
                    % Reshape
                    if strcmp(AxisOrder, 'XYCZPT')
                        stack = reshape(stack, [header.ImageLength, header.ImageWidth, channels, slices]);
                        stack = permute(stack, [1 2 4 3]);      % Swap channels and slices
                    elseif strcmp(AxisOrder, 'XYZCPT')
                        stack = reshape(stack, [header.ImageLength, header.ImageWidth, slices, channels]);
                    end

                    if header.BitsPerSample == 16
                        output_stack = zeros(size(stack), 'uint16');
                    else
                        output_stack = zeros(size(stack), 'single');
                    end

                    % RL deconvolution part
                    for channel_index = 1:channels
                        input_image = squeeze(stack(:, :, :, channel_index));
                        input_image = input_image - background(channel_index);      % Subtract background
                        input_image(input_image < 0) = 0;
                        FWHM = [FWHM_xy(channel_index), FWHM_xy(channel_index), FWHM_z(channel_index)];
                        output_image = decon_gpu(input_image, FWHM, iter_num(channel_index));

                        if (header.BitsPerSample == 16)
                            if max(output_image, [], 'all') <= 65535
                                output_image = uint16(output_image);
                            else
                                output_image = uint16(65535 * output_image ./ max(output_image, [], 'all'));
                            end
                        end

                        output_stack(:, :, :, channel_index) = output_image;
                    end
                    % Save tiff stack file
                    filepath_output = strcat(path_output, '\', name_common, '_Pos', num2str(position_index), '_decon.tif');
                    if channels == 1
                        ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, header.spacing, 'z');
                    else
                        if slices == 1
                            ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, 0.2, 'c');
                        else
                            output_stack = permute(output_stack, [1 2 4 3]);                % Swap channels and slices
                            ImageJ_formatted_TIFF.WriteTifStack(output_stack, filepath_output, header.resolution, header.spacing, 'cz');
                        end
                    end
                end
            end
            
            disp('Finished.')
            app.UIFigure.Pointer = 'arrow';
            app.ProceedButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UPLSAPO60XSPanel = uipanel(app.UIFigure);
            app.UPLSAPO60XSPanel.Title = 'UPLSAPO60XS';
            app.TabGroup_60X = uitabgroup(app.UPLSAPO60XSPanel);
            app.iSIMTab_60X = uitab(app.TabGroup_60X);
            app.iSIMTab_60X.Title = 'iSIM';
            app.DAPILabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.DAPILabel_60X_iSIM.Text = '405 nm: DAPI';
            app.GFPLabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.GFPLabel_60X_iSIM.Text = '488 nm: GFP';
            app.mCherryLabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.mCherryLabel_60X_iSIM.Text = '561 nm: mCherry';
            app.XYEditField_60X_iSIM_DAPI = uieditfield(app.iSIMTab_60X, 'numeric');
            app.XYEditField_60X_iSIM_DAPI.Limits = [100 400];
            app.XYEditField_60X_iSIM_DAPI.Value = 180;
            app.ZEditField_60X_iSIM_DAPI = uieditfield(app.iSIMTab_60X, 'numeric');
            app.ZEditField_60X_iSIM_DAPI.Limits = [300 1000];
            app.ZEditField_60X_iSIM_DAPI.Value = 466;
            app.XYEditField_60X_iSIM_GFP = uieditfield(app.iSIMTab_60X, 'numeric');
            app.XYEditField_60X_iSIM_GFP.Limits = [100 400];
            app.XYEditField_60X_iSIM_GFP.Value = 212;
            app.ZEditField_60X_iSIM_GFP = uieditfield(app.iSIMTab_60X, 'numeric');
            app.ZEditField_60X_iSIM_GFP.Limits = [300 1000];
            app.ZEditField_60X_iSIM_GFP.Value = 533;
            app.XYEditField_60X_iSIM_mCherry = uieditfield(app.iSIMTab_60X, 'numeric');
            app.XYEditField_60X_iSIM_mCherry.Limits = [100 400];
            app.XYEditField_60X_iSIM_mCherry.Value = 239;
            app.ZEditField_60X_iSIM_mCherry = uieditfield(app.iSIMTab_60X, 'numeric');
            app.ZEditField_60X_iSIM_mCherry.Limits = [300 1000];
            app.ZEditField_60X_iSIM_mCherry.Value = 600;
            app.cy5Label_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.cy5Label_60X_iSIM.Text = '640 nm: cy5';
            app.XYEditField_60X_iSIM_cy5 = uieditfield(app.iSIMTab_60X, 'numeric');
            app.XYEditField_60X_iSIM_cy5.Limits = [100 400];
            app.XYEditField_60X_iSIM_cy5.Value = 277;
            app.ZEditField_60X_iSIM_cy5 = uieditfield(app.iSIMTab_60X, 'numeric');
            app.ZEditField_60X_iSIM_cy5.Limits = [300 1000];
            app.ZEditField_60X_iSIM_cy5.Value = 710;
            app.XYnmLabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.XYnmLabel_60X_iSIM.Text = 'XY (nm):';
            app.ZnmLabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.ZnmLabel_60X_iSIM.Text = 'Z (nm):';
            app.BackgroundEditField_60X_iSIM_cy5 = uieditfield(app.iSIMTab_60X, 'numeric');
            app.BackgroundEditField_60X_iSIM_cy5.Limits = [0 500];
            app.BackgroundEditField_60X_iSIM_cy5.Value = 100;
            app.BackgroundEditField_60X_iSIM_mCherry = uieditfield(app.iSIMTab_60X, 'numeric');
            app.BackgroundEditField_60X_iSIM_mCherry.Limits = [0 500];
            app.BackgroundEditField_60X_iSIM_mCherry.Value = 100;
            app.BackgroundEditField_60X_iSIM_GFP = uieditfield(app.iSIMTab_60X, 'numeric');
            app.BackgroundEditField_60X_iSIM_GFP.Limits = [0 500];
            app.BackgroundEditField_60X_iSIM_GFP.Value = 100;
            app.BackgroundEditField_60X_iSIM_DAPI = uieditfield(app.iSIMTab_60X, 'numeric');
            app.BackgroundEditField_60X_iSIM_DAPI.Limits = [0 500];
            app.BackgroundEditField_60X_iSIM_DAPI.Value = 100;
            app.BackgroundLabel_60X_iSIM = uilabel(app.iSIMTab_60X);
            app.BackgroundLabel_60X_iSIM.Text = 'Background:';
            app.EpiTab_60X = uitab(app.TabGroup_60X);
            app.EpiTab_60X.Title = 'Epi';
            app.DAPILabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.DAPILabel_60X_Epi.Text = '405 nm: DAPI';
            app.GFPLabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.GFPLabel_60X_Epi.Text = '488 nm: GFP';
            app.mCherryLabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.mCherryLabel_60X_Epi.Text = '561 nm: mCherry';
            app.XYEditField_60X_Epi_DAPI = uieditfield(app.EpiTab_60X, 'numeric');
            app.XYEditField_60X_Epi_DAPI.Limits = [100 400];
            app.XYEditField_60X_Epi_DAPI.Value = 180;
            app.ZEditField_60X_Epi_DAPI = uieditfield(app.EpiTab_60X, 'numeric');
            app.ZEditField_60X_Epi_DAPI.Limits = [300 1000];
            app.ZEditField_60X_Epi_DAPI.Value = 466;
            app.XYEditField_60X_Epi_GFP = uieditfield(app.EpiTab_60X, 'numeric');
            app.XYEditField_60X_Epi_GFP.Limits = [100 400];
            app.XYEditField_60X_Epi_GFP.Value = 212;
            app.ZEditField_60X_Epi_GFP = uieditfield(app.EpiTab_60X, 'numeric');
            app.ZEditField_60X_Epi_GFP.Limits = [300 1000];
            app.ZEditField_60X_Epi_GFP.Value = 533;
            app.XYEditField_60X_Epi_mCherry = uieditfield(app.EpiTab_60X, 'numeric');
            app.XYEditField_60X_Epi_mCherry.Limits = [100 400];
            app.XYEditField_60X_Epi_mCherry.Value = 239;
            app.ZEditField_60X_Epi_mCherry = uieditfield(app.EpiTab_60X, 'numeric');
            app.ZEditField_60X_Epi_mCherry.Limits = [300 1000];
            app.ZEditField_60X_Epi_mCherry.Value = 600;
            app.cy5Label_60X_Epi = uilabel(app.EpiTab_60X);
            app.cy5Label_60X_Epi.Text = '640 nm: cy5';
            app.XYEditField_60X_Epi_cy5 = uieditfield(app.EpiTab_60X, 'numeric');
            app.XYEditField_60X_Epi_cy5.Limits = [100 400];
            app.XYEditField_60X_Epi_cy5.Value = 277;
            app.ZEditField_60X_Epi_cy5 = uieditfield(app.EpiTab_60X, 'numeric');
            app.ZEditField_60X_Epi_cy5.Limits = [300 1000];
            app.ZEditField_60X_Epi_cy5.Value = 710;
            app.XYnmLabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.XYnmLabel_60X_Epi.Text = 'XY (nm):';
            app.ZnmLabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.ZnmLabel_60X_Epi.Text = 'Z (nm):';
            app.BackgroundEditField_60X_Epi_cy5 = uieditfield(app.EpiTab_60X, 'numeric');
            app.BackgroundEditField_60X_Epi_cy5.Limits = [0 500];
            app.BackgroundEditField_60X_Epi_cy5.Value = 100;
            app.BackgroundEditField_60X_Epi_mCherry = uieditfield(app.EpiTab_60X, 'numeric');
            app.BackgroundEditField_60X_Epi_mCherry.Limits = [0 500];
            app.BackgroundEditField_60X_Epi_mCherry.Value = 100;
            app.BackgroundEditField_60X_Epi_GFP = uieditfield(app.EpiTab_60X, 'numeric');
            app.BackgroundEditField_60X_Epi_GFP.Limits = [0 500];
            app.BackgroundEditField_60X_Epi_GFP.Value = 100;
            app.BackgroundEditField_60X_Epi_DAPI = uieditfield(app.EpiTab_60X, 'numeric');
            app.BackgroundEditField_60X_Epi_DAPI.Limits = [0 500];
            app.BackgroundEditField_60X_Epi_DAPI.Value = 100;
            app.BackgroundLabel_60X_Epi = uilabel(app.EpiTab_60X);
            app.BackgroundLabel_60X_Epi.Text = 'Background:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.filepathEditField = uieditfield(app.UIFigure, 'text');
            app.filepathEditField.Value = 'Please load an raw iSIM / Epi image';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UPLSAPO100XOPanel = uipanel(app.UIFigure);
            app.UPLSAPO100XOPanel.Title = 'UPLSAPO100XO';
            app.TabGroup_100X = uitabgroup(app.UPLSAPO100XOPanel);
            app.iSIMTab_100X = uitab(app.TabGroup_100X);
            app.iSIMTab_100X.Title = 'iSIM';
            app.DAPILabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.DAPILabel_100X_iSIM.Text = '405 nm: DAPI';
            app.GFPLabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.GFPLabel_100X_iSIM.Text = '488 nm: GFP';
            app.mCherryLabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.mCherryLabel_100X_iSIM.Text = '561 nm: mCherry';
            app.XYEditField_100X_iSIM_DAPI = uieditfield(app.iSIMTab_100X, 'numeric');
            app.XYEditField_100X_iSIM_DAPI.Limits = [100 400];
            app.XYEditField_100X_iSIM_DAPI.Value = 155;
            app.ZEditField_100X_iSIM_DAPI = uieditfield(app.iSIMTab_100X, 'numeric');
            app.ZEditField_100X_iSIM_DAPI.Limits = [300 1000];
            app.ZEditField_100X_iSIM_DAPI.Value = 350;
            app.XYEditField_100X_iSIM_GFP = uieditfield(app.iSIMTab_100X, 'numeric');
            app.XYEditField_100X_iSIM_GFP.Limits = [100 400];
            app.XYEditField_100X_iSIM_GFP.Value = 184;
            app.ZEditField_100X_iSIM_GFP = uieditfield(app.iSIMTab_100X, 'numeric');
            app.ZEditField_100X_iSIM_GFP.Limits = [300 1000];
            app.ZEditField_100X_iSIM_GFP.Value = 400;
            app.XYEditField_100X_iSIM_mCherry = uieditfield(app.iSIMTab_100X, 'numeric');
            app.XYEditField_100X_iSIM_mCherry.Limits = [100 400];
            app.XYEditField_100X_iSIM_mCherry.Value = 207;
            app.ZEditField_100X_iSIM_mCherry = uieditfield(app.iSIMTab_100X, 'numeric');
            app.ZEditField_100X_iSIM_mCherry.Limits = [300 1000];
            app.ZEditField_100X_iSIM_mCherry.Value = 450;
            app.cy5Label_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.cy5Label_100X_iSIM.Text = '640 nm: cy5';
            app.XYEditField_100X_iSIM_cy5 = uieditfield(app.iSIMTab_100X, 'numeric');
            app.XYEditField_100X_iSIM_cy5.Limits = [100 400];
            app.XYEditField_100X_iSIM_cy5.Value = 240;
            app.ZEditField_100X_iSIM_cy5 = uieditfield(app.iSIMTab_100X, 'numeric');
            app.ZEditField_100X_iSIM_cy5.Limits = [300 1000];
            app.ZEditField_100X_iSIM_cy5.Value = 533;
            app.XYnmLabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.XYnmLabel_100X_iSIM.Text = 'XY (nm):';
            app.ZnmLabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.ZnmLabel_100X_iSIM.Text = 'Z (nm):';
            app.BackgroundEditField_100X_iSIM_cy5 = uieditfield(app.iSIMTab_100X, 'numeric');
            app.BackgroundEditField_100X_iSIM_cy5.Limits = [0 500];
            app.BackgroundEditField_100X_iSIM_cy5.Value = 100;
            app.BackgroundEditField_100X_iSIM_mCherry = uieditfield(app.iSIMTab_100X, 'numeric');
            app.BackgroundEditField_100X_iSIM_mCherry.Limits = [0 500];
            app.BackgroundEditField_100X_iSIM_mCherry.Value = 100;
            app.BackgroundEditField_100X_iSIM_GFP = uieditfield(app.iSIMTab_100X, 'numeric');
            app.BackgroundEditField_100X_iSIM_GFP.Limits = [0 500];
            app.BackgroundEditField_100X_iSIM_GFP.Value = 100;
            app.BackgroundEditField_100X_iSIM_DAPI = uieditfield(app.iSIMTab_100X, 'numeric');
            app.BackgroundEditField_100X_iSIM_DAPI.Limits = [0 500];
            app.BackgroundEditField_100X_iSIM_DAPI.Value = 100;
            app.BackgroundLabel_100X_iSIM = uilabel(app.iSIMTab_100X);
            app.BackgroundLabel_100X_iSIM.Text = 'Background:';
            app.EpiTab_100X = uitab(app.TabGroup_100X);
            app.EpiTab_100X.Title = 'Epi';
            app.DAPILabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.DAPILabel_100X_Epi.Text = '405 nm: DAPI';
            app.GFPLabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.GFPLabel_100X_Epi.Text = '488 nm: GFP';
            app.mCherryLabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.mCherryLabel_100X_Epi.Text = '561 nm: mCherry';
            app.XYEditField_100X_Epi_DAPI = uieditfield(app.EpiTab_100X, 'numeric');
            app.XYEditField_100X_Epi_DAPI.Limits = [100 400];
            app.XYEditField_100X_Epi_DAPI.Value = 155;
            app.ZEditField_100X_Epi_DAPI = uieditfield(app.EpiTab_100X, 'numeric');
            app.ZEditField_100X_Epi_DAPI.Limits = [300 1000];
            app.ZEditField_100X_Epi_DAPI.Value = 350;
            app.XYEditField_100X_Epi_GFP = uieditfield(app.EpiTab_100X, 'numeric');
            app.XYEditField_100X_Epi_GFP.Limits = [100 400];
            app.XYEditField_100X_Epi_GFP.Value = 184;
            app.ZEditField_100X_Epi_GFP = uieditfield(app.EpiTab_100X, 'numeric');
            app.ZEditField_100X_Epi_GFP.Limits = [300 1000];
            app.ZEditField_100X_Epi_GFP.Value = 400;
            app.XYEditField_100X_Epi_mCherry = uieditfield(app.EpiTab_100X, 'numeric');
            app.XYEditField_100X_Epi_mCherry.Limits = [100 400];
            app.XYEditField_100X_Epi_mCherry.Value = 207;
            app.ZEditField_100X_Epi_mCherry = uieditfield(app.EpiTab_100X, 'numeric');
            app.ZEditField_100X_Epi_mCherry.Limits = [300 1000];
            app.ZEditField_100X_Epi_mCherry.Value = 450;
            app.cy5Label_100X_Epi = uilabel(app.EpiTab_100X);
            app.cy5Label_100X_Epi.Text = '640 nm: cy5';
            app.XYEditField_100X_Epi_cy5 = uieditfield(app.EpiTab_100X, 'numeric');
            app.XYEditField_100X_Epi_cy5.Limits = [100 400];
            app.XYEditField_100X_Epi_cy5.Value = 240;
            app.ZEditField_100X_Epi_cy5 = uieditfield(app.EpiTab_100X, 'numeric');
            app.ZEditField_100X_Epi_cy5.Limits = [300 1000];
            app.ZEditField_100X_Epi_cy5.Value = 533;
            app.XYnmLabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.XYnmLabel_100X_Epi.Text = 'XY (nm):';
            app.ZnmLabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.ZnmLabel_100X_Epi.Text = 'Z (nm):';
            app.BackgroundEditField_100X_Epi_cy5 = uieditfield(app.EpiTab_100X, 'numeric');
            app.BackgroundEditField_100X_Epi_cy5.Limits = [0 500];
            app.BackgroundEditField_100X_Epi_cy5.Value = 100;
            app.BackgroundEditField_100X_Epi_mCherry = uieditfield(app.EpiTab_100X, 'numeric');
            app.BackgroundEditField_100X_Epi_mCherry.Limits = [0 500];
            app.BackgroundEditField_100X_Epi_mCherry.Value = 100;
            app.BackgroundEditField_100X_Epi_GFP = uieditfield(app.EpiTab_100X, 'numeric');
            app.BackgroundEditField_100X_Epi_GFP.Limits = [0 500];
            app.BackgroundEditField_100X_Epi_GFP.Value = 100;
            app.BackgroundEditField_100X_Epi_DAPI = uieditfield(app.EpiTab_100X, 'numeric');
            app.BackgroundEditField_100X_Epi_DAPI.Limits = [0 500];
            app.BackgroundEditField_100X_Epi_DAPI.Value = 100;
            app.BackgroundLabel_100X_Epi = uilabel(app.EpiTab_100X);
            app.BackgroundLabel_100X_Epi.Text = 'Background:';
            app.IterNumEditField_cy5 = uieditfield(app.UIFigure, 'numeric');
            app.IterNumEditField_cy5.Limits = [1 30];
            app.IterNumEditField_cy5.Value = 10;
            app.IterNumEditField_mCherry = uieditfield(app.UIFigure, 'numeric');
            app.IterNumEditField_mCherry.Limits = [1 30];
            app.IterNumEditField_mCherry.Value = 10;
            app.IterNumEditField_GFP = uieditfield(app.UIFigure, 'numeric');
            app.IterNumEditField_GFP.Limits = [1 30];
            app.IterNumEditField_GFP.Value = 10;
            app.IterNumEditField_DAPI = uieditfield(app.UIFigure, 'numeric');
            app.IterNumEditField_DAPI.Limits = [1 30];
            app.IterNumEditField_DAPI.Value = 10;
            app.IterNumLabel = uilabel(app.UIFigure);
            app.IterNumLabel.Text = 'Iter Num:';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.Copyright = uilabel(app.UIFigure);
            app.Copyright.Text = {'iSIM deconvolution app (v1.0)'; 'Copyright: Xuesong Li in Shroff Lab'; 'Janelia Research Campus of HHMI'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iSIM_decon_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end